classdef xbDesignPlaneExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        xbDesignPlaneExplorerUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        EvaluatefeasibilityareaButton  matlab.ui.control.Button
        CurrentfileplaneisLabel        matlab.ui.control.Label
        PlaneNameEditField             matlab.ui.control.EditField
        StacklengthEditField           matlab.ui.control.EditField
        StacklengthEditFieldLabel      matlab.ui.control.Label
        DefaultplotButton              matlab.ui.control.Button
        AddplotButton                  matlab.ui.control.Button
        NsEditField                    matlab.ui.control.EditField
        NsEditFieldLabel               matlab.ui.control.Label
        PowerevaluationLabel           matlab.ui.control.Label
        VdcEditField                   matlab.ui.control.EditField
        VdcLabel                       matlab.ui.control.Label
        CloseallButton                 matlab.ui.control.Button
        ExporttoWorkSpaceButton        matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        DrawButton                     matlab.ui.control.Button
        MotorSelectionLabel            matlab.ui.control.Label
        bEditField                     matlab.ui.control.EditField
        bLabel                         matlab.ui.control.Label
        xEditField                     matlab.ui.control.EditField
        xLabel                         matlab.ui.control.Label
        Plot3DButton                   matlab.ui.control.Button
        Plot2DButton                   matlab.ui.control.Button
        SaveplaneButton                matlab.ui.control.Button
        LoadDesignPlaneButton          matlab.ui.control.Button
        ClearexportButton              matlab.ui.control.Button
        SelectallButton                matlab.ui.control.Button
        AvailabledataListBoxLabel      matlab.ui.control.Label
        AvailabledataListBox           matlab.ui.control.ListBox
        UIAxes                         matlab.ui.control.UIAxes
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        SyReLabel                      matlab.ui.control.Label
        xbDesignPlaneExplorerLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        map % Data included in the GUI section
        filename % Description
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function SDE_update(app)
            [app.map,app.filename] = SDE_loadPlane(app.map);
            SDE_SetParameters(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Set figure at the center of the screen
            app.xbDesignPlaneExplorerUIFigure.Units = 'pixels';
            screenPos = get(groot,'ScreenSize');
            figPos = app.xbDesignPlaneExplorerUIFigure.Position;
            figPos(1:2)=screenPos(3:4)/2-figPos(3:4)/2;
            app.xbDesignPlaneExplorerUIFigure.Position = figPos;
             
            mfilesPath = fileparts(which('SDE_SetParameters.m'));
            addpath(mfilesPath);

            if length(varargin)==1
                [app.map,app.filename] = SDE_loadPlane(varargin{1});
            elseif length(varargin)==2
                [app.map,app.filename] = SDE_loadPlane(varargin{1},varargin{2});
            else
                app.map = [];
            end

            SDE_SetParameters(app);


        end

        % Button pushed function: LoadDesignPlaneButton
        function LoadDesignPlaneButtonPushed(app, event)
            [app.map,app.filename] = SDE_loadPlane();
            SDE_SetParameters(app);            
        end

        % Button pushed function: SaveplaneButton
        function SaveplaneButtonPushed(app, event)
            map = app.map;
            map.dataSelect = {'T','PF'};
            hfig = SDE_plot(map,0);
            [filename,pathname] = uiputfile(checkPathSyntax([cd '\*.fig']),'Save Design Plane');
            if filename
                set(hfig,'Filename',[pathname filename]);
                savePrintFigure(hfig);
                app.filename = filename(1:end-4);
            end
            SDE_SetParameters(app);
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
            clc
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.map.xSelect = eval(value);
            if app.map.xSelect>max(app.map.xx,[],'all')
                app.map.xSelect = max(app.map.xx,[],'all');
            end
            if app.map.xSelect<min(app.map.xx,[],'all')
                app.map.xSelect = min(app.map.xx,[],'all');
            end
            SDE_SetParameters(app);
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.map.bSelect = eval(value);
            if app.map.bSelect>max(app.map.bb,[],'all')
                app.map.bSelect = max(app.map.bb,[],'all');
            end
            if app.map.bSelect<min(app.map.bb,[],'all')
                app.map.bSelect = min(app.map.bb,[],'all');
            end
            SDE_SetParameters(app);
        end

        % Callback function
        function EvaluateButtonPushed(app, event)
            SDE_evalMotor(app.map);
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            SDE_evalMotor(app.map);
            SDE_saveMotor(app.map,0);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            SDE_saveMotor(app.map,1);
        end

        % Button pushed function: ExporttoWorkSpaceButton
        function ExporttoWorkSpaceButtonPushed(app, event)
            SDE_toWorkSpace(app.map);
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            app.map.dataSelect = app.map.dataAvailable;
            SDE_SetParameters(app);
        end

        % Button pushed function: ClearexportButton
        function ClearexportButtonPushed(app, event)
            app.map.dataSelect = [];
            SDE_SetParameters(app);
        end

        % Button pushed function: Plot2DButton
        function Plot2DButtonPushed(app, event)
            SDE_plot(app.map,0);
        end

        % Value changed function: AvailabledataListBox
        function AvailabledataListBoxValueChanged(app, event)
            value = app.AvailabledataListBox.Value;
            app.map.dataSelect = value;
            SDE_SetParameters(app);
        end

        % Button pushed function: Plot3DButton
        function Plot3DButtonPushed(app, event)
            SDE_plot(app.map,1);
        end

        % Value changed function: NsEditField
        function NsEditFieldValueChanged(app, event)
            value = app.NsEditField.Value;
            kN = eval(value)/app.map.geo.win.Ns;
            app.map = SDE_scaleNs(app.map,kN);
            app.map.geo.win.Ns = eval(value);
            app.map = SDE_evalBaseSpeed(app.map);
            SDE_SetParameters(app);
        end

        % Value changed function: VdcEditField
        function VdcEditFieldValueChanged(app, event)
            value = app.VdcEditField.Value;
            app.map.Vdc = eval(value);
            app.map = SDE_evalBaseSpeed(app.map);
            SDE_SetParameters(app);
        end

        % Button pushed function: AddplotButton
        function AddplotButtonPushed(app, event)
            SDE_addPlot(app);
        end

        % Button pushed function: DefaultplotButton
        function DefaultplotButtonPushed(app, event)
            SDE_SetParameters(app);
        end

        % Value changed function: StacklengthEditField
        function StacklengthEditFieldValueChanged(app, event)
            value = app.StacklengthEditField.Value;
            kL = eval(value)/app.map.geo.l;
            app.map = SDE_scaleL(app.map,kL);
            app.map.geo.l = eval(value);
            app.map = SDE_evalBaseSpeed(app.map);
            SDE_SetParameters(app);
        end

        % Value changed function: PlaneNameEditField
        function PlaneNameEditFieldValueChanged(app, event)
            value = app.PlaneNameEditField.Value;
            
        end

        % Button pushed function: EvaluatefeasibilityareaButton
        function EvaluatefeasibilityareaButtonPushed(app, event)
            SDE_targetDesign(app.map);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.xbDesignPlaneExplorerUIFigure = uifigure('Visible', 'off');
            colormap(app.xbDesignPlaneExplorerUIFigure, 'parula');
            app.xbDesignPlaneExplorerUIFigure.Name = 'SyR-e (x,b) Design Plane Explorer';
            app.xbDesignPlaneExplorerUIFigure.Icon = 'icon.png';
            app.xbDesignPlaneExplorerLabel = uilabel(app.xbDesignPlaneExplorerUIFigure);
            app.xbDesignPlaneExplorerLabel.Text = '(x,b) Design Plane Explorer';
            app.SyReLabel = uilabel(app.xbDesignPlaneExplorerUIFigure);
            app.SyReLabel.Text = 'SyR-e';
            app.Image = uiimage(app.xbDesignPlaneExplorerUIFigure);
            app.Image.ImageSource = 'syreHeader.png';
            app.Image2 = uiimage(app.xbDesignPlaneExplorerUIFigure);
            app.Image2.ImageSource = 'POLI2021-PEIC_colori.png';
            app.Panel = uipanel(app.xbDesignPlaneExplorerUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '(x,b) Design Plane')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'b')
            zlabel(app.UIAxes, 'Z')
            app.AvailabledataListBox = uilistbox(app.GridLayout);
            app.AvailabledataListBox.Items = {};
            app.AvailabledataListBox.Multiselect = 'on';
            app.AvailabledataListBox.ValueChangedFcn = createCallbackFcn(app, @AvailabledataListBoxValueChanged, true);
            app.AvailabledataListBox.Value = {};
            app.AvailabledataListBoxLabel = uilabel(app.GridLayout);
            app.AvailabledataListBoxLabel.Text = 'Available data:';
            app.SelectallButton = uibutton(app.GridLayout, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.ClearexportButton = uibutton(app.GridLayout, 'push');
            app.ClearexportButton.ButtonPushedFcn = createCallbackFcn(app, @ClearexportButtonPushed, true);
            app.ClearexportButton.Text = 'Clear selection';
            app.LoadDesignPlaneButton = uibutton(app.GridLayout, 'push');
            app.LoadDesignPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDesignPlaneButtonPushed, true);
            app.LoadDesignPlaneButton.Text = 'Load Design Plane';
            app.SaveplaneButton = uibutton(app.GridLayout, 'push');
            app.SaveplaneButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplaneButtonPushed, true);
            app.SaveplaneButton.Text = 'Save plane';
            app.Plot2DButton = uibutton(app.GridLayout, 'push');
            app.Plot2DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2DButtonPushed, true);
            app.Plot2DButton.Text = 'Plot 2D';
            app.Plot3DButton = uibutton(app.GridLayout, 'push');
            app.Plot3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DButtonPushed, true);
            app.Plot3DButton.Text = 'Plot 3D';
            app.xLabel = uilabel(app.GridLayout);
            app.xLabel.Text = 'x =';
            app.xEditField = uieditfield(app.GridLayout, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.bLabel = uilabel(app.GridLayout);
            app.bLabel.Text = 'b =';
            app.bEditField = uieditfield(app.GridLayout, 'text');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.MotorSelectionLabel = uilabel(app.GridLayout);
            app.MotorSelectionLabel.Text = 'Motor Selection';
            app.DrawButton = uibutton(app.GridLayout, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExporttoWorkSpaceButton = uibutton(app.GridLayout, 'push');
            app.ExporttoWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkSpaceButtonPushed, true);
            app.ExporttoWorkSpaceButton.Text = 'Export to WorkSpace';
            app.CloseallButton = uibutton(app.GridLayout, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.VdcLabel = uilabel(app.GridLayout);
            app.VdcLabel.Text = 'Vdc =';
            app.VdcEditField = uieditfield(app.GridLayout, 'text');
            app.VdcEditField.ValueChangedFcn = createCallbackFcn(app, @VdcEditFieldValueChanged, true);
            app.PowerevaluationLabel = uilabel(app.GridLayout);
            app.PowerevaluationLabel.Text = 'Power evaluation';
            app.NsEditFieldLabel = uilabel(app.GridLayout);
            app.NsEditFieldLabel.Text = 'Ns =';
            app.NsEditField = uieditfield(app.GridLayout, 'text');
            app.NsEditField.ValueChangedFcn = createCallbackFcn(app, @NsEditFieldValueChanged, true);
            app.AddplotButton = uibutton(app.GridLayout, 'push');
            app.AddplotButton.ButtonPushedFcn = createCallbackFcn(app, @AddplotButtonPushed, true);
            app.AddplotButton.Text = 'Add plot';
            app.DefaultplotButton = uibutton(app.GridLayout, 'push');
            app.DefaultplotButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultplotButtonPushed, true);
            app.DefaultplotButton.Text = 'Default plot';
            app.StacklengthEditFieldLabel = uilabel(app.GridLayout);
            app.StacklengthEditFieldLabel.Text = 'Stack length =';
            app.StacklengthEditField = uieditfield(app.GridLayout, 'text');
            app.StacklengthEditField.ValueChangedFcn = createCallbackFcn(app, @StacklengthEditFieldValueChanged, true);
            app.PlaneNameEditField = uieditfield(app.GridLayout, 'text');
            app.PlaneNameEditField.ValueChangedFcn = createCallbackFcn(app, @PlaneNameEditFieldValueChanged, true);
            app.CurrentfileplaneisLabel = uilabel(app.GridLayout);
            app.CurrentfileplaneisLabel.Text = 'Current file plane is:';
            app.EvaluatefeasibilityareaButton = uibutton(app.GridLayout, 'push');
            app.EvaluatefeasibilityareaButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluatefeasibilityareaButtonPushed, true);
            app.EvaluatefeasibilityareaButton.Text = 'Evaluate feasibility area';
            % Show the figure after all components are created
            app.xbDesignPlaneExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xbDesignPlaneExplorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.xbDesignPlaneExplorerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.xbDesignPlaneExplorerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.xbDesignPlaneExplorerUIFigure)
        end
    end
end